classdef SpectralMeasurements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralMeasurement            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ProjectMenu                    matlab.ui.container.Menu
        ProjectSave                    matlab.ui.container.Menu
        ProjectOpen                    matlab.ui.container.Menu
        ProjectNew                     matlab.ui.container.Menu
        SweepMenu                      matlab.ui.container.Menu
        SweepSave                      matlab.ui.container.Menu
        SweepOpen                      matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        EffectiveIndexCalculationMenu  matlab.ui.container.Menu
        ConnectionSetupMenu            matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        TabGroup2                      matlab.ui.container.TabGroup
        ProjectTab                     matlab.ui.container.Tab
        MeasurementsPanel              matlab.ui.container.Panel
        tblMeasurements                matlab.ui.control.Table
        LMS_1Tab                       matlab.ui.container.Tab
        LightwaveMeasurementSystem01Label  matlab.ui.control.Label
        ConnectionPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        InstrumentDropDownLabel        matlab.ui.control.Label
        panel01_InstrSelect1           matlab.ui.control.DropDown
        panel01_ConnectButton          matlab.ui.control.Button
        panel01_instrConnStat          matlab.ui.control.Lamp
        LaserOutputLampLabel           matlab.ui.control.Label
        panel01_LaserOutputLamp        matlab.ui.control.Lamp
        LaserControlPanel_2            matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        WavelengthSpinnerLabel         matlab.ui.control.Label
        panel01_txtWavelength          matlab.ui.control.Spinner
        OutputPowerSpinnerLabel        matlab.ui.control.Label
        panel01_txtPower               matlab.ui.control.Spinner
        nmLabel                        matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        panel01_btnLaserOn             matlab.ui.control.StateButton
        PowerSensorPanel               matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        AveragingTSpinnerLabel         matlab.ui.control.Label
        panel01_AveragingTSpinner      matlab.ui.control.Spinner
        RangeDropDownLabel             matlab.ui.control.Label
        panel01_RangeDropDown          matlab.ui.control.DropDown
        pwrMeas1                       matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        panel01_btnPowerScan           matlab.ui.control.StateButton
        panel01_cbAutoRange            matlab.ui.control.CheckBox
        dBmLabel_3                     matlab.ui.control.Label
        OpticalSwitchPanel_2           matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        SwitchAKnobLabel               matlab.ui.control.Label
        panel01_swAKnob                matlab.ui.control.DiscreteKnob
        SwitchBKnobLabel               matlab.ui.control.Label
        panel01_swBKnob                matlab.ui.control.DiscreteKnob
        LMS_2Tab                       matlab.ui.container.Tab
        SweepTab                       matlab.ui.container.Tab
        ScanParametersPanel            matlab.ui.container.Panel
        nmLabel_2                      matlab.ui.control.Label
        nmLabel_3                      matlab.ui.control.Label
        ScanStartSpinnerLabel          matlab.ui.control.Label
        spinStart                      matlab.ui.control.Spinner
        ScanStopSpinnerLabel           matlab.ui.control.Label
        spinStop                       matlab.ui.control.Spinner
        StepSizeSpinnerLabel           matlab.ui.control.Label
        spinStep                       matlab.ui.control.Spinner
        nmLabel_4                      matlab.ui.control.Label
        ScanSpeedLabel                 matlab.ui.control.Label
        spinSpeed                      matlab.ui.control.Spinner
        nmsLabel                       matlab.ui.control.Label
        RangeLabel                     matlab.ui.control.Label
        RangeDropDown_Sweep            matlab.ui.control.DropDown
        DataAnalysisTab_2              matlab.ui.container.Tab
        Peakfinder1RedPanel            matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        HeightLabel                    matlab.ui.control.Label
        HeightSpinner                  matlab.ui.control.Spinner
        ProminenceLabel                matlab.ui.control.Label
        ProminenceSpinner              matlab.ui.control.Spinner
        MaxWidthLabel                  matlab.ui.control.Label
        MaxWidthSpinner                matlab.ui.control.Spinner
        MinWidthLabel                  matlab.ui.control.Label
        MinWidthSpinner                matlab.ui.control.Spinner
        PeakSpacingLabel               matlab.ui.control.Label
        DistanceSpinner                matlab.ui.control.Spinner
        dBmLabel_4                     matlab.ui.control.Label
        nmLabel_5                      matlab.ui.control.Label
        nmLabel_6                      matlab.ui.control.Label
        nmLabel_7                      matlab.ui.control.Label
        MinPeakHeightCheckBox          matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox      matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakWidthCheckBox           matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox        matlab.ui.control.CheckBox
        Peakfinder2GreenPanel          matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        HeightSpinner_2Label           matlab.ui.control.Label
        HeightSpinner_2                matlab.ui.control.Spinner
        ProminenceSpinner_2Label       matlab.ui.control.Label
        ProminenceSpinner_2            matlab.ui.control.Spinner
        MaxWidthSpinner_2Label         matlab.ui.control.Label
        MaxWidthSpinner_2              matlab.ui.control.Spinner
        MinWidthSpinner_2Label         matlab.ui.control.Label
        MinWidthSpinner_2              matlab.ui.control.Spinner
        PeakSpacingLabel_2             matlab.ui.control.Label
        DistanceSpinner_2              matlab.ui.control.Spinner
        dBmLabel_5                     matlab.ui.control.Label
        nmLabel_8                      matlab.ui.control.Label
        nmLabel_9                      matlab.ui.control.Label
        nmLabel_10                     matlab.ui.control.Label
        MinPeakHeightCheckBox_2        matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox_2    matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakWidthCheckBox_2         matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox_2      matlab.ui.control.CheckBox
        InterferenceAnalysisPanel      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        TypeofdeviceDropDownLabel      matlab.ui.control.Label
        TypeofdeviceDropDown           matlab.ui.control.DropDown
        dnmEditFieldLabel              matlab.ui.control.Label
        dnmEditField                   matlab.ui.control.NumericEditField
        GridLayout5                    matlab.ui.container.GridLayout
        neffectiveEditFieldLabel       matlab.ui.control.Label
        neffectiveEditField            matlab.ui.control.NumericEditField
        FindPeaksButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UITable2                       matlab.ui.control.Table
        HoldPlotCheckBox               matlab.ui.control.CheckBox
        logger                         matlab.ui.control.TextArea
        UIAxes01                       matlab.ui.control.UIAxes
        btnRunSweep                    matlab.ui.control.StateButton
        UIComponentsTable              matlab.ui.control.Table
        SaveButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        connection                 
    end
    
    properties (Access = private)
        Timer % Description
        connPanel01 % Description
        LockLaser % Description
        saveFolder % Description
        mData % Description
        
        sweep % Structure with members {Pseudrandom Identification (), Sweep Parameters, Spectra, Analysis Parameters, Peak Analysis results}
    end
    
    methods (Access = private)
        
        function [status, idn] = sm_connect(app,g)
            status = 0; idn = 0;
            try
                if strcmp(g.status,'open'), fclose(g); end %Force to reset the connection.
                fopen(g); 
                idn = send(g,'*IDN?');
                status = 1;
                fclose(g); 
            catch EM
                log(app,EM.message)
                
            end
        end
        
        function TimerFcn(app, src, event)
        %put on a try and add switch off
            if strcmp(app.panel01_instrConnStat.Enable,'on')
                g = app.connPanel01.gpib;
                if strcmp(g.status,'open') 
                    val = str2num(send(g,'read1:pow?'));
                    app.pwrMeas1.Text = num2str(val,'%.4f');
                end
            end
        end
        
        function laserStat = switchLaser(app,g, laserStat)
            %Check if the laser is locked and unlock in necessary
            app.LockLaser = false;
            fprintf(g,'lock?');lockStat = str2double(fscanf(g));
            if lockStat
                fprintf(g,'lock 0,1234');
                app.LockLaser = true;
                log(app,'Unlocking the laser using the default password.')
                lockStat = send(g,'lock?');
                if ~lockStat, log(app,'Laser unlocked.');
                else, log(app,'Unabled to unlock the laser.');
                end
            end
                
            %Switch the laser
            send(g,"outp "+laserStat);
            laserStat = send(g,"outp?");
            app.panel01_LaserOutputLamp.Enable = laserStat;
            
            %Lock the laser if the initial state was locked
            if ~laserStat && app.LockLaser, fprintf(g,'lock 1,1234');end
        end
        
        function wav = setLaserWav(app,g, wav)
            %Sets the laser01 central wavelenght and returns the
            %configured value in nm

            send(g,"sour0:chan1:wav " + num2str(wav,'%5.3f')+'nm');
            wav = str2num(send(g,'sour0:chan1:wav?'))*1e9;
        end
        
        function pow = setLaserPower(app,g,pow)
        
            %Set the power unit as dBm
            send(g,'sour0:pow:unit dbm');
            send(g,"sour0:chan1:pow " + num2str(pow,'%5.0f'));
            str2num(send(g,'sour0:chan1:pow?'));
        end
        
        function enableControls(app, value)
            app.panel01_instrConnStat.Enable = value;                      
            app.panel01_swAKnob.Enable = value;
            app.btnRunSweep.Enable = value;
            app.panel01_btnPowerScan.Enable = value;
            app.panel01_btnLaserOn.Enable = value;
        end
        
        function setPowerScan(app,value)
            g = app.connPanel01.gpib;
            if value
                try  
                    fopen(g);
                    send(g,'*CLS');
                    opcStat = send(g,'*OPC?'); 
                    if opcStat
                        range = app.panel01_RangeDropDown.Value;    %Range in dBm
                        avgT = app.panel01_AveragingTSpinner.Value*1e-3; %Averaging tim ein seconds
                        
                        send(g,"sens1:pow:atim "+ num2str(avgT,'%5.2f') + 's');
                        avgT = str2num(send(g,"sens1:pow:atim?"));
                        if ~app.panel01_cbAutoRange.Value
                            send(g,"sens1:pow:rang:auto 0");
                            send(g,"sens1:pow:rang "+ range);
                            range =  str2num(send(g,"sens1:pow:rang?"));
                            log(app,"Configured sensor with averaging time: " + avgT + " and range " + num2str(range,'%5.2f') +' dBm.');
                        else
                            send(g,"sens1:pow:rang:auto 1");
                            log(app,"Configured sensor with averaging time: "+avgT+" and automatic range.")
                        end
                        
                        start(app.Timer);
                    else
                        app.Switch.Value = false;
                        fclose(g);
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    stop(app.Timer);
                    app.panel01_btnPowerScan.Value = false;
                    if ~isempty(app.connPanel01.gpib), fclose(app.connPanel01.gpib);end
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            else
                stop(app.Timer);
                fclose(g);
            end
        end
        
        function results = processData(app)
            data = app.sweep.spectra;
            filterStat{1} = [ app.MinPeakHeightCheckBox.Value,...
                        app.MinPeakProminenceCheckBox.Value,...
                        app.MaxPeakWidthCheckBox.Value,...
                        app.MinPeakWidthCheckBox.Value,...
                        app.MinPeakDistanceCheckBox.Value];
    
            filterStat{2} = [ app.MinPeakHeightCheckBox_2.Value,...
                        app.MinPeakProminenceCheckBox_2.Value,...
                        app.MaxPeakWidthCheckBox_2.Value,...
                        app.MinPeakWidthCheckBox_2.Value,...
                        app.MinPeakDistanceCheckBox_2.Value];
            
            filterParam{1} = [app.HeightSpinner.Value,...
                        app.ProminenceSpinner.Value,...
                        app.MaxWidthSpinner.Value,...
                        app.MinWidthSpinner.Value,...
                        app.DistanceSpinner.Value
                        ];
                    
            filterParam{2} = [app.HeightSpinner_2.Value,...
                        app.ProminenceSpinner_2.Value,...
                        app.MaxWidthSpinner_2.Value,...
                        app.MinWidthSpinner_2.Value,...
                        app.DistanceSpinner_2.Value
                        ];
            
            
            ax1 = app.UIAxes01;            
            [pks,wav,FSR]=getPeaks(data,filterStat,filterParam);
            if ~isempty(pks) 
                if app.HoldPlotCheckBox.Value
                    hold(app.UIAxes01,'on');
                end
                plot (ax1,data(:,1),data(:,2), wav{1}, pks{1},'V','MarkerFaceColor','red'); 
                hold(app.UIAxes01,'off');
                
                xlabels = cellstr(num2str(wav{1}*1e9));
                lbls={};
                for i =1:length(wav{1}),lbls{i}=xlabels{i};end
                text(ax1,wav{1}, pks{1},lbls);
            else
                 plot (ax1,data(:,1),data(:,2));
            end
            
            
        end
    end
    
    methods (Access = public)
        
        function log(app,logText)
            oldlogText = app.logger.Value;
            maxLines = 100;
            if length(oldlogText)<maxLines
                logText = [logText; oldlogText];
            else
                logText = [logText;oldlogText(1:maxLines-1)];
            end
            app.logger.Value = logText;
        end
        
        
        function setWorkspace(app, configData)
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.UIComponentsTable.Data = configData;
            
            %Fill the combo boxes with the instruments available in the
            %configuration table
            comboStrings = strcat(string(configData.ID),'-',configData.Name);
            comboValues = configData.ID;
            app.panel01_InstrSelect1.Items = comboStrings;
            app.panel01_InstrSelect1.ItemsData = comboValues;
            
            
            %Define the GPIB connections
            app.connection = [];
            for i =1:size(configData,1)
                c = configData(i,:);
                app.connection(i).config = c;
                app.connection(i).ID = string(c.ID);
                app.connection(i).gpib = gpib(string(c.vendor), c.Board, c.GPIB);
                app.connection(i).status = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Name = ["Empty"];
            %Description = [""];
            %GPIB = [0];
            %Timeout = [30];
            %Enabled = [true];
            %            t = table(Name, Description, GPIB, Timeout, Enabled);
            
            %Clear any existing instruments
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end
            
            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
           
            app.Timer = timer('Period',0.3,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;
            
            setWorkspace(app, configData)
            app.saveFolder = "C:\Users\Public\Documents\Shared Data\Users\";
        end

        % Button pushed function: panel01_ConnectButton
        function panel01_btnConnectPushed(app, event)
            instrID = string(app.panel01_InstrSelect1.Value);
            t = app.UIComponentsTable.Data;
            status = false;
            
            clear c
            for connIndex = 1:length(app.connection)
                if strcmp(app.connection(connIndex).ID,instrID)
                    c = app.connection(connIndex);
                    g = c.gpib;
                    break
                end
            end
            
            
            enableControls(app,0);    
            if t(instrID,:).Enabled
                [status, idn] = sm_connect(app,g);
                if ~status
                    %try to restart the connection
                    config = c.config;
                    c.gpib = gpib(string(config.vendor), config.Board, config.GPIB);
                    [status, idn] = sm_connect(app,g);
                end
                
                if status
                    log(app,"Connected to "+idn);
                    app.panel01_instrConnStat.Enable='on';
                    
                    switch_slot = 4;
                    fopen(c.gpib);
                    swStat = send(c.gpib,"rout"+switch_slot+":chan1?"); %%CHECK THIS
                    
                    enableControls(app,1);
                    c.status = true;
                    app.connPanel01 = c;    %set the connection for the 1st panel as the one found for the selected ID.
                    
                    fclose(c.gpib);
                else
                    log(app,"The selected instrument does not respond.")
                    c.status = false;
                    app.connPanel01 = c;
                end
            else
                log(app,"The selected instrument is not enabled in the configuration.")
                
            end
                    

            if (~status)
                log(app,"Failed to connect to the measurement system.")
            end
        end

        % Value changed function: panel01_btnLaserOn
        function panel01_btnLaserOnPushed(app, event)
        %Sends a command to turn the laser on and updates the laser
        %parameters according to the opnes set.
        
            value = double(app.panel01_btnLaserOn.Value);
            closeConn = false;
            try  
                if strcmp(app.connPanel01.gpib.status,'closed')
                    closeConn = true; %Id the connection is closed, open it and use this flag to close it at the end
                    fopen(app.connPanel01.gpib);
                end
                
                %Check if the intsrument can receive commands (this could
                %be on a while to wait until the instrument is ready).
                
                opcStat = str2double(send(g,'*OPC?'));
                if opcStat
                    laserStat = switchLaser(app,app.connPanel01.gpib,value);
                    if laserStat
                        dblWav = setLaserWav(app,app.connPanel01.gpib, app.panel01_txtWavelength.Value);
                        dblPow = setLaserPower(app,app.connPanel01.gpib, app.panel01_txtPower.Value);
                        
                        app.panel01_txtWavelength.Value = dblWav;
                        app.panel01_txtPower.Value= dblPow;
                    end
                    

                else
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"The instrument is not ready.")
                end
            catch ME
                app.panel01_btnLaserOn.Value = false;
                log(app,"Error: "+ME.message);
                log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            
            if closeConn, fclose(app.connPanel01.gpib);end %Close the connection if the flag is raised


        end

        % Value changed function: panel01_btnPowerScan
        function panel01_btnPowerScanPushed(app, event)
            %This function will open the COM with the instrument and leave
            %it open for increase performance, and start the timed function
            %to query for the measurements in the Power Sensor.
            value = app.panel01_btnPowerScan.Value;
            setPowerScan(app, value);  
        end

        % Value changed function: panel01_swAKnob
        function panel01_swValueChanged(app, event)
            if app.connPanel01.status   
                value = app.panel01_swAKnob.Value;
                g = app.connPanel01.gpib;
                try 
                    closeConn = openGPIB(g);
                    stat = str2double(send(g,'*OPC?')); %Check if the intsrument can receive commands
                    if str2double(stat)
                        send(g,"rout4:chan1 A,"+value);
                        swStat = send(g,"rout4:chan1?");
                        app.panel01_swAKnob.Value = swStat(3); 
                        
                    else
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(g);end
            end
        end

        % Menu selected function: SweepSave
        function SweepSaveSelected(app, event)
            data = app.mData;
            file = datestr(now,'ddmmyyyy')+"_alldata.mat";
            path = app.saveFolder;
            
            [file,path] = uiputfile(strcat(path,file),'Save sweep data as');
            if path~=0
               
                save(strcat(path,file),'data');
                app.saveFolder = path;
            end
           figure(app.SpectralMeasurement);
        end

        % Menu selected function: SweepOpen
        function SweepOpenSelected(app, event)
            %try
                path = app.saveFolder;
                [file,path] = uigetfile(strcat(path,"*.mat"),'MultiSelect', 'on');
                
                if ~isempty(path)
                    
                    if path ~= 0
                        if ischar(file), files{1} = file;
                        else, files = file; end
                        
                        for i = 1:length(files)
                            data = load(strcat(path,files{i}));
                            app.sweep.spectra = data.data;
                            processData(app);
                            app.saveFolder = path;
                            
                            mData(:,:,i) =  data.data; 
                        end
                        app.mData = mData;
                    end
                end
                figure(app.SpectralMeasurement);
%             catch EM
%                 log(app,EM.message)
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectralMeasurement.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {857, 857};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {425, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SaveButton
        function ConfigSavePushed(app, event)
            configData = app.UIComponentsTable.Data;
            save('config.mat', 'configData','-mat');
        end

        % Value changed function: panel01_cbAutoRange
        function panel01_cbAutoRangeValueChanged(app, event)
            value = app.panel01_cbAutoRange.Value;
            if value
                app.panel01_RangeDropDown.Enable = false;
            else
                app.panel01_RangeDropDown.Enable = true;
            end
        end

        % Value changed function: panel01_txtWavelength
        function panel01_txtWavelengthValueChanged(app, event)
            laserStat = double(app.panel01_btnLaserOn.Value);
            if laserStat
                closeConn = false;
                try  
                    if strcmp(app.connPanel01.gpib.status,'closed') %Use this in other parts of the code as well to check for the connection status
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the instrument can receive commands
                    
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblWav = app.panel01_txtWavelength.Value;
                        if laserStat, dblWav = setLaserWav(app,app.connPanel01.gpib, dblWav); end
                        app.panel01_txtWavelength.Value = dblWav;
                    end
                catch ME
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end
        end

        % Close request function: SpectralMeasurement
        function SpectralMeasurementCloseRequest(app, event)
            %Make sure to close all connections and delete the objects
            
            for i = 1:length(app.connection)
                try 
                    fclose(app.connection(i).gpib);
                catch EM
                end
                delete(app.connection(i).gpib);
                
            end
            delete(app)
            clear app
        end

        % Value changed function: btnRunSweep
        function btnRunSweepValueChanged(app, event)
            value = app.btnRunSweep.Value;
            if value
                stop(app.Timer);
                app.panel01_btnPowerScan.Value = 0;
                app.panel01_btnLaserOn.Value = 0;
                opcStat = 0;
                g = app.connPanel01.gpib;
                if app.connPanel01.status
                    try fopen(g); catch ME, log(app,ME.message); end
                    
                    send(g,'*CLS');
                    opcStat = str2double(send(g,'*OPC?'));
                    
                    if opcStat
                        enableControls(app, 0);
                        app.sweep.parameters.channel = 1;
                        app.sweep.parameters.power = 3;             % Power in dBm (0dBm = 1mW)  -- Change to user input
                        app.sweep.parameters.starWav =  app.spinStart.Value*1e-9;  % start wavelength in m
                        app.sweep.parameters.stopWav =  app.spinStop.Value*1e-9;   % stop wavelength in m
                        app.sweep.parameters.step =     app.spinStep.Value*1e-9;   % stepsize in meters
                        app.sweep.parameters.sweepSpeed = app.spinSpeed.Value*1e-9;  % sweep speed in m/s
                        app.sweep.parameters.range = app.RangeDropDown_Sweep.Value;  % range in dBm in steps of 10, between [-70 and 40];
                          
                        %park it at the start wavelength and turn off laser 
                        dblWav = setLaserWav(app,g, app.sweep.parameters.starWav*1e9);
                        pause(2)
                        switchLaser(app,g, 0);
                        
                        % Prepare scan
                        [app.sweep.parameters,sweepP] = prepareScan(g,app.sweep.parameters);
                        if sweepP.stat
                            app.sweep.spectra = runSweep(g,app.sweep.parameters);
                            processData(app);
                        end
                        enableControls(app, 1);
                    end
                    fclose(g);
                end
            end
            app.btnRunSweep.Value = 0;
            enableControls(app, 1);
        end

        % Value changed function: panel01_txtPower
        function panel01_txtPowerValueChanged(app, event)

            if app.connPanel01.status
                
                try  
                    closeConn = false;
                    if strcmp(app.connPanel01.gpib.status,'closed')
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the ind=strument can receive commands
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblPow = app.panel01_txtPower.Value;
                        app.panel01_txtPower.Value = setLaserPower(app,app.connPanel01.gpib, dblPow);
                    end
                catch ME
                    app.panel01_btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end      
        end

        % Value changed function: panel01_RangeDropDown
        function panel01_RangeDropDownValueChanged(app, event)
            value = app.panel01_RangeDropDown.Value;
            
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            processData(app);
        end

        % Menu selected function: EffectiveIndexCalculationMenu
        function modeCalculatorSelected(app, event)
            ModeCalculator;
        end

        % Menu selected function: ProjectSave
        function ProjectSaveSelected(app, event)
            
        end

        % Menu selected function: ProjectOpen
        function ProjectOpenSelected(app, event)
            
        end

        % Menu selected function: ProjectNew
        function ProjectNewSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralMeasurement = uifigure('Visible', 'off');
            app.SpectralMeasurement.Name = 'Spectral Measurements';
            app.SpectralMeasurement.CloseRequestFcn = createCallbackFcn(app, @SpectralMeasurementCloseRequest, true);
            app.SpectralMeasurement.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.SpectralMeasurement);
            app.FileMenu.Text = 'File';
            app.ProjectMenu = uimenu(app.FileMenu);
            app.ProjectMenu.Text = 'Project';
            app.ProjectSave = uimenu(app.ProjectMenu);
            app.ProjectSave.MenuSelectedFcn = createCallbackFcn(app, @ProjectSaveSelected, true);
            app.ProjectSave.Text = 'Save';
            app.ProjectOpen = uimenu(app.ProjectMenu);
            app.ProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @ProjectOpenSelected, true);
            app.ProjectOpen.Text = 'Open';
            app.ProjectNew = uimenu(app.ProjectMenu);
            app.ProjectNew.MenuSelectedFcn = createCallbackFcn(app, @ProjectNewSelected, true);
            app.ProjectNew.Text = 'New';
            app.SweepMenu = uimenu(app.FileMenu);
            app.SweepMenu.Text = 'Sweep';
            app.SweepSave = uimenu(app.SweepMenu);
            app.SweepSave.MenuSelectedFcn = createCallbackFcn(app, @SweepSaveSelected, true);
            app.SweepSave.Text = 'Save';
            app.SweepOpen = uimenu(app.SweepMenu);
            app.SweepOpen.MenuSelectedFcn = createCallbackFcn(app, @SweepOpenSelected, true);
            app.SweepOpen.Text = 'Open';
            app.ToolsMenu = uimenu(app.SpectralMeasurement);
            app.ToolsMenu.Text = 'Tools';
            app.EffectiveIndexCalculationMenu = uimenu(app.ToolsMenu);
            app.EffectiveIndexCalculationMenu.MenuSelectedFcn = createCallbackFcn(app, @modeCalculatorSelected, true);
            app.EffectiveIndexCalculationMenu.Text = 'Effective Index Calculation';
            app.ConnectionSetupMenu = uimenu(app.ToolsMenu);
            app.ConnectionSetupMenu.Text = 'Connection Setup';
            app.GridLayout = uigridlayout(app.SpectralMeasurement);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'tandon_long_color.png';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ProjectTab = uitab(app.TabGroup2);
            app.ProjectTab.Title = 'Project';
            app.MeasurementsPanel = uipanel(app.ProjectTab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.tblMeasurements = uitable(app.MeasurementsPanel);
            app.tblMeasurements.ColumnName = {'Time'; 'Var 1'; 'Var 2'; 'Var 3'};
            app.tblMeasurements.RowName = {};
            app.LMS_1Tab = uitab(app.TabGroup2);
            app.LMS_1Tab.Title = 'LMS_1';
            app.LightwaveMeasurementSystem01Label = uilabel(app.LMS_1Tab);
            app.LightwaveMeasurementSystem01Label.Text = 'Lightwave Measurement System 01';
            app.ConnectionPanel = uipanel(app.LMS_1Tab);
            app.ConnectionPanel.Title = 'Connection';
            app.GridLayout8 = uigridlayout(app.ConnectionPanel);
            app.InstrumentDropDownLabel = uilabel(app.GridLayout8);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.panel01_InstrSelect1 = uidropdown(app.GridLayout8);
            app.panel01_InstrSelect1.ItemsData = {'1001'};
            app.panel01_InstrSelect1.Value = '1001';
            app.panel01_ConnectButton = uibutton(app.GridLayout8, 'push');
            app.panel01_ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @panel01_btnConnectPushed, true);
            app.panel01_ConnectButton.Text = 'Connect';
            app.panel01_instrConnStat = uilamp(app.GridLayout8);
            app.panel01_instrConnStat.Enable = 'off';
            app.LaserOutputLampLabel = uilabel(app.GridLayout8);
            app.LaserOutputLampLabel.Text = 'Laser Output';
            app.panel01_LaserOutputLamp = uilamp(app.GridLayout8);
            app.panel01_LaserOutputLamp.Enable = 'off';
            app.LaserControlPanel_2 = uipanel(app.LMS_1Tab);
            app.LaserControlPanel_2.Title = 'Laser Control';
            app.GridLayout9 = uigridlayout(app.LaserControlPanel_2);
            app.WavelengthSpinnerLabel = uilabel(app.GridLayout9);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.panel01_txtWavelength = uispinner(app.GridLayout9);
            app.panel01_txtWavelength.Step = 0.1;
            app.panel01_txtWavelength.Limits = [1500 1640];
            app.panel01_txtWavelength.ValueDisplayFormat = '%.3f';
            app.panel01_txtWavelength.ValueChangedFcn = createCallbackFcn(app, @panel01_txtWavelengthValueChanged, true);
            app.panel01_txtWavelength.Value = 1550;
            app.OutputPowerSpinnerLabel = uilabel(app.GridLayout9);
            app.OutputPowerSpinnerLabel.Text = 'Output Power';
            app.panel01_txtPower = uispinner(app.GridLayout9);
            app.panel01_txtPower.Step = 0.1;
            app.panel01_txtPower.Limits = [0 13];
            app.panel01_txtPower.ValueDisplayFormat = '%5.2f';
            app.panel01_txtPower.ValueChangedFcn = createCallbackFcn(app, @panel01_txtPowerValueChanged, true);
            app.panel01_txtPower.Value = 3;
            app.nmLabel = uilabel(app.GridLayout9);
            app.nmLabel.Text = 'nm';
            app.dBmLabel = uilabel(app.GridLayout9);
            app.dBmLabel.Text = 'dBm';
            app.panel01_btnLaserOn = uibutton(app.GridLayout9, 'state');
            app.panel01_btnLaserOn.ValueChangedFcn = createCallbackFcn(app, @panel01_btnLaserOnPushed, true);
            app.panel01_btnLaserOn.Enable = 'off';
            app.panel01_btnLaserOn.Text = 'On';
            app.PowerSensorPanel = uipanel(app.LMS_1Tab);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.GridLayout10 = uigridlayout(app.PowerSensorPanel);
            app.AveragingTSpinnerLabel = uilabel(app.GridLayout10);
            app.AveragingTSpinnerLabel.Text = 'Averaging T';
            app.panel01_AveragingTSpinner = uispinner(app.GridLayout10);
            app.panel01_AveragingTSpinner.Limits = [1 1000];
            app.panel01_AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.panel01_AveragingTSpinner.Value = 200;
            app.RangeDropDownLabel = uilabel(app.GridLayout10);
            app.RangeDropDownLabel.Enable = 'off';
            app.RangeDropDownLabel.Text = 'Range';
            app.panel01_RangeDropDown = uidropdown(app.GridLayout10);
            app.panel01_RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.panel01_RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.panel01_RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @panel01_RangeDropDownValueChanged, true);
            app.panel01_RangeDropDown.Enable = 'off';
            app.panel01_RangeDropDown.Value = '-20DBM';
            app.pwrMeas1 = uilabel(app.GridLayout10);
            app.pwrMeas1.Text = '0.00';
            app.msLabel = uilabel(app.GridLayout10);
            app.msLabel.Text = 'ms';
            app.panel01_btnPowerScan = uibutton(app.GridLayout10, 'state');
            app.panel01_btnPowerScan.ValueChangedFcn = createCallbackFcn(app, @panel01_btnPowerScanPushed, true);
            app.panel01_btnPowerScan.Enable = 'off';
            app.panel01_btnPowerScan.Text = 'Run';
            app.panel01_cbAutoRange = uicheckbox(app.GridLayout10);
            app.panel01_cbAutoRange.ValueChangedFcn = createCallbackFcn(app, @panel01_cbAutoRangeValueChanged, true);
            app.panel01_cbAutoRange.Text = 'Auto';
            app.panel01_cbAutoRange.Value = true;
            app.dBmLabel_3 = uilabel(app.GridLayout10);
            app.dBmLabel_3.Text = 'dBm';
            app.OpticalSwitchPanel_2 = uipanel(app.LMS_1Tab);
            app.OpticalSwitchPanel_2.Title = 'Optical Switch';
            app.GridLayout11 = uigridlayout(app.OpticalSwitchPanel_2);
            app.SwitchAKnobLabel = uilabel(app.GridLayout11);
            app.SwitchAKnobLabel.Enable = 'off';
            app.SwitchAKnobLabel.Text = 'Switch A';
            app.panel01_swAKnob = uiknob(app.GridLayout11, 'discrete');
            app.panel01_swAKnob.Items = {'1', '2', '3', '4'};
            app.panel01_swAKnob.ValueChangedFcn = createCallbackFcn(app, @panel01_swValueChanged, true);
            app.panel01_swAKnob.Enable = 'off';
            app.panel01_swAKnob.Value = '1';
            app.SwitchBKnobLabel = uilabel(app.GridLayout11);
            app.SwitchBKnobLabel.Enable = 'off';
            app.SwitchBKnobLabel.Text = 'Switch B';
            app.panel01_swBKnob = uiknob(app.GridLayout11, 'discrete');
            app.panel01_swBKnob.Items = {'1', '2', '3', '4'};
            app.panel01_swBKnob.Enable = 'off';
            app.panel01_swBKnob.Value = '1';
            app.LMS_2Tab = uitab(app.TabGroup2);
            app.LMS_2Tab.Title = 'LMS_2';
            app.SweepTab = uitab(app.TabGroup2);
            app.SweepTab.Title = 'Sweep';
            app.ScanParametersPanel = uipanel(app.SweepTab);
            app.ScanParametersPanel.Title = 'Scan Parameters';
            app.nmLabel_2 = uilabel(app.ScanParametersPanel);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.ScanParametersPanel);
            app.nmLabel_3.Text = 'nm';
            app.ScanStartSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.ScanParametersPanel);
            app.spinStart.Limits = [1500 1640];
            app.spinStart.Value = 1520;
            app.ScanStopSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.ScanParametersPanel);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1580;
            app.StepSizeSpinnerLabel = uilabel(app.ScanParametersPanel);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.ScanParametersPanel);
            app.spinStep.Step = 0.01;
            app.spinStep.Limits = [0.01 1];
            app.spinStep.Value = 0.1;
            app.nmLabel_4 = uilabel(app.ScanParametersPanel);
            app.nmLabel_4.Text = 'nm';
            app.ScanSpeedLabel = uilabel(app.ScanParametersPanel);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uispinner(app.ScanParametersPanel);
            app.spinSpeed.Step = 0.1;
            app.spinSpeed.Limits = [0.1 10];
            app.spinSpeed.ValueDisplayFormat = '%2.1f';
            app.spinSpeed.Value = 1;
            app.nmsLabel = uilabel(app.ScanParametersPanel);
            app.nmsLabel.Text = 'nm/s';
            app.RangeLabel = uilabel(app.ScanParametersPanel);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown_Sweep = uidropdown(app.ScanParametersPanel);
            app.RangeDropDown_Sweep.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown_Sweep.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.RangeDropDown_Sweep.Value = '10';
            app.DataAnalysisTab_2 = uitab(app.TabGroup2);
            app.DataAnalysisTab_2.Title = 'Data Analysis';
            app.Peakfinder1RedPanel = uipanel(app.DataAnalysisTab_2);
            app.Peakfinder1RedPanel.Title = 'Peak finder 1 (Red)';
            app.GridLayout7 = uigridlayout(app.Peakfinder1RedPanel);
            app.HeightLabel = uilabel(app.GridLayout7);
            app.HeightLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout7);
            app.HeightSpinner.Value = -35;
            app.ProminenceLabel = uilabel(app.GridLayout7);
            app.ProminenceLabel.Text = 'Prominence';
            app.ProminenceSpinner = uispinner(app.GridLayout7);
            app.ProminenceSpinner.Value = 15;
            app.MaxWidthLabel = uilabel(app.GridLayout7);
            app.MaxWidthLabel.Text = 'Max Width';
            app.MaxWidthSpinner = uispinner(app.GridLayout7);
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthLabel = uilabel(app.GridLayout7);
            app.MinWidthLabel.Text = 'Min Width';
            app.MinWidthSpinner = uispinner(app.GridLayout7);
            app.PeakSpacingLabel = uilabel(app.GridLayout7);
            app.PeakSpacingLabel.Text = 'Peak Spacing';
            app.DistanceSpinner = uispinner(app.GridLayout7);
            app.DistanceSpinner.Value = 3;
            app.dBmLabel_4 = uilabel(app.GridLayout7);
            app.dBmLabel_4.Text = 'dBm';
            app.nmLabel_5 = uilabel(app.GridLayout7);
            app.nmLabel_5.Text = 'nm';
            app.nmLabel_6 = uilabel(app.GridLayout7);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.GridLayout7);
            app.nmLabel_7.Text = 'nm';
            app.MinPeakHeightCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakHeightCheckBox.Text = '';
            app.MinPeakProminenceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakProminenceCheckBox.Text = '';
            app.MinPeakProminenceCheckBox.Value = true;
            app.MaxPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MaxPeakWidthCheckBox.Text = '';
            app.MaxPeakWidthCheckBox.Value = true;
            app.MinPeakWidthCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakWidthCheckBox.Text = '';
            app.MinPeakDistanceCheckBox = uicheckbox(app.GridLayout7);
            app.MinPeakDistanceCheckBox.Text = '';
            app.Peakfinder2GreenPanel = uipanel(app.DataAnalysisTab_2);
            app.Peakfinder2GreenPanel.Title = 'Peak finder 2 (Green)';
            app.GridLayout6 = uigridlayout(app.Peakfinder2GreenPanel);
            app.HeightSpinner_2Label = uilabel(app.GridLayout6);
            app.HeightSpinner_2Label.Text = 'Height';
            app.HeightSpinner_2 = uispinner(app.GridLayout6);
            app.HeightSpinner_2.Value = -35;
            app.ProminenceSpinner_2Label = uilabel(app.GridLayout6);
            app.ProminenceSpinner_2Label.Text = 'Prominence';
            app.ProminenceSpinner_2 = uispinner(app.GridLayout6);
            app.ProminenceSpinner_2.Value = 15;
            app.MaxWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MaxWidthSpinner_2Label.Text = 'Max Width';
            app.MaxWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2Label = uilabel(app.GridLayout6);
            app.MinWidthSpinner_2Label.Text = 'Min Width';
            app.MinWidthSpinner_2 = uispinner(app.GridLayout6);
            app.MinWidthSpinner_2.Value = 0.5;
            app.PeakSpacingLabel_2 = uilabel(app.GridLayout6);
            app.PeakSpacingLabel_2.Text = 'Peak Spacing';
            app.DistanceSpinner_2 = uispinner(app.GridLayout6);
            app.DistanceSpinner_2.Value = 3;
            app.dBmLabel_5 = uilabel(app.GridLayout6);
            app.dBmLabel_5.Text = 'dBm';
            app.nmLabel_8 = uilabel(app.GridLayout6);
            app.nmLabel_8.Text = 'nm';
            app.nmLabel_9 = uilabel(app.GridLayout6);
            app.nmLabel_9.Text = 'nm';
            app.nmLabel_10 = uilabel(app.GridLayout6);
            app.nmLabel_10.Text = 'nm';
            app.MinPeakHeightCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakHeightCheckBox_2.Text = '';
            app.MinPeakProminenceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakProminenceCheckBox_2.Text = '';
            app.MaxPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MaxPeakWidthCheckBox_2.Text = '';
            app.MinPeakWidthCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakWidthCheckBox_2.Text = '';
            app.MinPeakDistanceCheckBox_2 = uicheckbox(app.GridLayout6);
            app.MinPeakDistanceCheckBox_2.Text = '';
            app.InterferenceAnalysisPanel = uipanel(app.DataAnalysisTab_2);
            app.InterferenceAnalysisPanel.Title = 'Interference Analysis';
            app.GridLayout3 = uigridlayout(app.InterferenceAnalysisPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TypeofdeviceDropDownLabel = uilabel(app.GridLayout4);
            app.TypeofdeviceDropDownLabel.Text = 'Type of device';
            app.TypeofdeviceDropDown = uidropdown(app.GridLayout4);
            app.TypeofdeviceDropDown.Items = {'Resonator', 'Interferometer', 'Other'};
            app.TypeofdeviceDropDown.Value = 'Resonator';
            app.dnmEditFieldLabel = uilabel(app.GridLayout4);
            app.dnmEditFieldLabel.Text = 'd (nm)';
            app.dnmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.dnmEditField.Value = 62831;
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.neffectiveEditFieldLabel = uilabel(app.GridLayout5);
            app.neffectiveEditFieldLabel.Text = 'n effective ';
            app.neffectiveEditField = uieditfield(app.GridLayout5, 'numeric');
            app.neffectiveEditField.Limits = [1 50];
            app.neffectiveEditField.Value = 3.4;
            app.FindPeaksButton = uibutton(app.GridLayout5, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable2 = uitable(app.RightPanel);
            app.UITable2.ColumnName = {'Peak'; 'Wavelength'; 'FSR'; 'FWHM'; 'Q. Factor'; 'Group Index'; 'Loss Coeficient'};
            app.UITable2.RowName = {};
            app.HoldPlotCheckBox = uicheckbox(app.RightPanel);
            app.HoldPlotCheckBox.Text = 'Hold Plot';
            app.logger = uitextarea(app.RightPanel);
            app.logger.Value = {'Spectral Measurements 1.1  (C) NYU PRL 2019'};
            app.UIAxes01 = uiaxes(app.RightPanel);
            title(app.UIAxes01, 'Spectrum')
            xlabel(app.UIAxes01, 'wavelength (m)')
            ylabel(app.UIAxes01, 'intensity (dBm)')
            app.btnRunSweep = uibutton(app.RightPanel, 'state');
            app.btnRunSweep.ValueChangedFcn = createCallbackFcn(app, @btnRunSweepValueChanged, true);
            app.btnRunSweep.Enable = 'off';
            app.btnRunSweep.Text = 'Run Sweep';
            app.UIComponentsTable = uitable(app.SpectralMeasurement);
            app.UIComponentsTable.ColumnName = {'ID'; 'Name'; 'Type'; 'Description'; 'Vendor'; 'Board'; 'GPIB Port'; 'Timeout'; 'Enabled'};
            app.UIComponentsTable.RowName = {};
            app.UIComponentsTable.ColumnEditable = true;
            app.SaveButton = uibutton(app.SpectralMeasurement, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigSavePushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.SpectralMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralMeasurements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralMeasurement)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralMeasurement)
        end
    end
end